classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        wavEditFieldLabel   matlab.ui.control.Label
        wavEditField        matlab.ui.control.EditField
        Label_2             matlab.ui.control.Label
        EditField           matlab.ui.control.EditField
        Label_3             matlab.ui.control.Label
        EditField_2         matlab.ui.control.EditField
        Label               matlab.ui.control.Label
        byTJUstudentsLabel  matlab.ui.control.Label
        start               matlab.ui.control.Image
        Image2              matlab.ui.control.Image
        Image3              matlab.ui.control.Image
        Image4              matlab.ui.control.Image
        UIAxes              matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes_3            matlab.ui.control.UIAxes
        UIAxes_4            matlab.ui.control.UIAxes
        UIAxes_5            matlab.ui.control.UIAxes
        UIAxes_6            matlab.ui.control.UIAxes
        sound1              matlab.ui.control.Image
        sound2              matlab.ui.control.Image
        sound0              matlab.ui.control.Image
        Label_4             matlab.ui.control.Label
        Spinner_1           matlab.ui.control.Spinner
        Label_5             matlab.ui.control.Label
        EditHz_1            matlab.ui.control.NumericEditField
        Label_6             matlab.ui.control.Label
        Image5              matlab.ui.control.Image
        Label_7             matlab.ui.control.Label
        EditHz_2            matlab.ui.control.NumericEditField
        Label_8             matlab.ui.control.Label
        Image5_2            matlab.ui.control.Image
        Spinner_2Label      matlab.ui.control.Label
        Spinner_2           matlab.ui.control.Spinner
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: EditField_2, start
        function click_start(app, event)
            input = app.wavEditField.Value;
            n1 = app.Spinner_1.Value;
            wc1 = app.EditHz_1.Value;
            n2 = app.Spinner_2.Value;
            wc2 = app.EditHz_2.Value;
            % read original voice
            try
                [s,fs]=audioread(input);
                % draw original
                cla(app.UIAxes); 
                plot(app.UIAxes,s);
                
                S=read_voice(s,fs); % fft of s
                cla(app.UIAxes_2); % draw fft of original
                plot(app.UIAxes_2,abs(S));
                
                % filter voice
                [s1,s2,S1,S2]=filter_sound(s,n1,wc1,n2,wc2);
                % draw voice1
                cla(app.UIAxes_3);
                plot(app.UIAxes_3,s1);
                cla(app.UIAxes_4)
                plot(app.UIAxes_4,abs(S1));
                %draw voice2
                cla(app.UIAxes_5);
                plot(app.UIAxes_5,s2);
                cla(app.UIAxes_6)
                plot(app.UIAxes_6,abs(S2));
                
                % write two voice into file
                s1_name = app.EditField.Value;
                s2_name = app.EditField_2.Value;
                try
                    audiowrite(s1_name,s1,fs);
                    audiowrite(s2_name,s1,fs);
                catch ErrorInfo
                    warndlg(ErrorInfo.message);
                end
                %write_voice(s1,output1,s2,output2,fs);
            catch msg
                warndlg(msg.message);
            end
        end

        % Image clicked function: sound1
        function sound1Click(app, event)
            file_name = app.EditField.Value;
            try
                [s ,fs] = audioread(file_name);
                sound(s,fs);
            catch ME
                warndlg(ME);
            end
        end

        % Image clicked function: sound2
        function sound2Click(app, event)
            file_name = app.EditField_2.Value;
            try
                [s ,fs] = audioread(file_name);
                sound(s,fs);
            catch ME
                warndlg(ME);
            end
        end

        % Image clicked function: sound0
        function sound0Click(app, event)
            file_name = app.wavEditField.Value;
            try
                [s ,fs] = audioread(file_name);
                sound(s,fs);
            catch ME
                warndlg(ME);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.wavEditFieldLabel = uilabel(app.UIFigure);
            app.wavEditFieldLabel.Text = '信号输入文件（.wav）';
            app.wavEditField = uieditfield(app.UIFigure, 'text');
            app.wavEditField.Value = 'voice0.wav';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '| 信号2输出文件路径';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 's2.wav';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '| 信号1输出文件路径';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @click_start, true);
            app.EditField_2.Value = 's1.wav';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = ' 声音信号分离系统 ';
            app.byTJUstudentsLabel = uilabel(app.UIFigure);
            app.byTJUstudentsLabel.Text = 'by TJU students';
            app.start = uiimage(app.UIFigure);
            app.start.ImageClickedFcn = createCallbackFcn(app, @click_start, true);
            app.start.ImageSource = 'start.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'xitong.png';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'xitong-2.png';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'zhuzuoquan.png';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '原始语音信号采样后时域波形')
            xlabel(app.UIAxes, '时间')
            ylabel(app.UIAxes, '幅值')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '原始语音信号采样后频谱图')
            xlabel(app.UIAxes_2, '频率（Hz）')
            ylabel(app.UIAxes_2, '频率幅值')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '分离出的语音信号1时域波形')
            xlabel(app.UIAxes_3, '时间')
            ylabel(app.UIAxes_3, '幅值')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, '分离出的语音信号2时域波形')
            xlabel(app.UIAxes_4, '时间')
            ylabel(app.UIAxes_4, '幅值')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, '分离出的语音信号1频谱图')
            xlabel(app.UIAxes_5, '频率（Hz）')
            ylabel(app.UIAxes_5, '频率幅值')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, '分离出的语音信号2频谱图')
            xlabel(app.UIAxes_6, '频率（Hz）')
            ylabel(app.UIAxes_6, '频率幅值')
            app.sound1 = uiimage(app.UIFigure);
            app.sound1.ImageClickedFcn = createCallbackFcn(app, @sound1Click, true);
            app.sound1.ImageSource = 'sound.png';
            app.sound2 = uiimage(app.UIFigure);
            app.sound2.ImageClickedFcn = createCallbackFcn(app, @sound2Click, true);
            app.sound2.ImageSource = 'sound.png';
            app.sound0 = uiimage(app.UIFigure);
            app.sound0.ImageClickedFcn = createCallbackFcn(app, @sound0Click, true);
            app.sound0.ImageSource = 'sound0.png';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '阶数';
            app.Spinner_1 = uispinner(app.UIFigure);
            app.Spinner_1.Value = 5;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '截止频率';
            app.EditHz_1 = uieditfield(app.UIFigure, 'numeric');
            app.EditHz_1.Value = 0.15;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '低通滤波器';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = 'filter.png';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '截止频率';
            app.EditHz_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditHz_2.Value = 0.75;
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '高通滤波器';
            app.Image5_2 = uiimage(app.UIFigure);
            app.Image5_2.ImageSource = 'filter.png';
            app.Spinner_2Label = uilabel(app.UIFigure);
            app.Spinner_2Label.Text = '阶数';
            app.Spinner_2 = uispinner(app.UIFigure);
            app.Spinner_2.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end