classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Button                matlab.ui.control.Button
        Label_3               matlab.ui.control.Label
        Label_4               matlab.ui.control.Label
        Label_5               matlab.ui.control.Label
        Label_6               matlab.ui.control.Label
        TabGroup              matlab.ui.container.TabGroup
        Tab                   matlab.ui.container.Tab
        Button_2              matlab.ui.control.Button
        Label_10              matlab.ui.control.Label
        Label_11              matlab.ui.control.Label
        Label_7               matlab.ui.control.Label
        EditField             matlab.ui.control.NumericEditField
        UIAxes5_2             matlab.ui.control.UIAxes
        UIAxes6_2             matlab.ui.control.UIAxes
        Label_8               matlab.ui.control.Label
        Label_9               matlab.ui.control.Label
        UIAxes5               matlab.ui.control.UIAxes
        UIAxes6               matlab.ui.control.UIAxes
        Image                 matlab.ui.control.Image
        HzLabel_3             matlab.ui.control.Label
        Image2                matlab.ui.control.Image
        Tab_2                 matlab.ui.container.Tab
        Button_3              matlab.ui.control.Button
        Label_14              matlab.ui.control.Label
        Label_15              matlab.ui.control.Label
        Label_17              matlab.ui.control.Label
        EditField_2           matlab.ui.control.NumericEditField
        UIAxes5_3             matlab.ui.control.UIAxes
        UIAxes6_3             matlab.ui.control.UIAxes
        Label_19              matlab.ui.control.Label
        Label_20              matlab.ui.control.Label
        UIAxes5_4             matlab.ui.control.UIAxes
        UIAxes6_4             matlab.ui.control.UIAxes
        Image_2               matlab.ui.control.Image
        HzLabel_4             matlab.ui.control.Label
        Image2_2              matlab.ui.control.Image
        Image6_2              matlab.ui.control.Image
        byTJUstudnetsLabel_2  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes3               matlab.ui.control.UIAxes
        UIAxes4               matlab.ui.control.UIAxes
        Label_18              matlab.ui.control.Label
        Image3                matlab.ui.control.Image
        Image4                matlab.ui.control.Image
        Image5                matlab.ui.control.Image
    end

    
    properties (Access = private)
        filename % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [file,path,index] = uigetfile({'*.jpg';'*.bmp';'*.gif'},'选择图片');
            if (index == 1)
                cla(app.UIAxes);
                cla(app.UIAxes5);
                cla(app.UIAxes5_4);
                cla(app.UIAxes2);
                cla(app.UIAxes3);
                cla(app.UIAxes4);
                cla(app.UIAxes6);
                cla(app.UIAxes6_4);
                cla(app.UIAxes5_2);
                cla(app.UIAxes6_2);
                cla(app.UIAxes5_3);
                cla(app.UIAxes6_3);
                
                app.filename=[path file];
                img0=imread(app.filename);
                img=rgb2gray(img0);
                img=double(img);
                f=fft2(img);        %傅里叶变换
                f=fftshift(f);      %使图像对称
                r=real(f);          %图像频域实部
                i=imag(f);          %图像频域虚部
                margin=log(abs(f));      %图像幅度谱，加log便于显示
                phase=log(angle(f)*180/pi);     %图像相位谱
                l=log(f);
                imshow(img0, 'Parent',app.UIAxes);
                imshow(img0, 'Parent',app.UIAxes5);
                imshow(img0, 'Parent',app.UIAxes5_4);
                imshow(l,[],'Parent',app.UIAxes2);
                imshow(margin,[],'Parent',app.UIAxes3);
                imshow(phase,[],'Parent',app.UIAxes4);
                
                A=img;
                B=fftshift(fft2(A));
                E= ifft2(ifftshift(B));
                imshow(log(abs(E)), [], 'Parent',app.UIAxes6);
                imshow(log(abs(E)), [], 'Parent',app.UIAxes6_4);
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            A=imread(app.filename);
            A=rgb2gray(A);
            B=fftshift(fft2(A));
            B1=B;
            E= ifft2(ifftshift(B));
            
            [m,n]=size(A);
            
            freq=app.EditField.Value;
            
            for i=1:m
                for j=1:n
                    temp=(i - (m - 1) / 2) ^ 2 + (j - (n - 1) / 2) ^ 2;
                    if  temp < freq ^ 2
                        B(i, j) = 1;
                        
                    end
                end
            end
            
            
            C=ifft2(ifftshift(B));
            imshow(log(abs(B)), [], "Parent", app.UIAxes5_2);
            imshow(log(abs(C)), [ ], "Parent", app.UIAxes6_2);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            A=imread(app.filename);

            A=rgb2gray(A);
            B=fftshift(fft2(A));
            E= ifft2(ifftshift(B));
            
            [m,n]=size(A);
            %m1=m/2;n1=n/2;
            H1=ones(m,n);
            freq=app.EditField_2.Value;
            %H1(m1-freq:m1+freq, n1-freq:n1+freq)=1;
            
            for i=1:m
                for j=1:n
                    temp=(i - (n - 1) / 2) ^ 2 + (j - (m - 1) / 2) ^ 2;
                    if  temp >= freq ^ 2
                        H1(i, j) = 0;
                        
                    end
                end
            end
            LOWPASS1=B.* H1;
            size(LOWPASS1)
            
            C=ifft2(ifftshift(LOWPASS1));
            
            imshow(log(abs(LOWPASS1)), [ ], "Parent", app.UIAxes5_3);
            imshow(log(abs(C)), [ ], "Parent", app.UIAxes6_3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '选择图片';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '源图像';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '图像频谱';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '图像幅度谱';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '图像相位谱';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '高通滤波器';
            app.Button_2 = uibutton(app.Tab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '生成';
            app.Label_10 = uilabel(app.Tab);
            app.Label_10.Text = '高通';
            app.Label_11 = uilabel(app.Tab);
            app.Label_11.Text = '高通变换图像';
            app.Label_7 = uilabel(app.Tab);
            app.Label_7.Text = '设置高通滤波器截止频率';
            app.EditField = uieditfield(app.Tab, 'numeric');
            app.UIAxes5_2 = uiaxes(app.Tab);
            title(app.UIAxes5_2, '')
            xlabel(app.UIAxes5_2, '')
            ylabel(app.UIAxes5_2, '')
            app.UIAxes6_2 = uiaxes(app.Tab);
            title(app.UIAxes6_2, '')
            xlabel(app.UIAxes6_2, '')
            ylabel(app.UIAxes6_2, '')
            app.UIAxes6_2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.Label_8 = uilabel(app.Tab);
            app.Label_8.Text = '源图像';
            app.Label_9 = uilabel(app.Tab);
            app.Label_9.Text = '未滤波的逆变换图像';
            app.UIAxes5 = uiaxes(app.Tab);
            title(app.UIAxes5, '')
            xlabel(app.UIAxes5, '')
            ylabel(app.UIAxes5, '')
            app.UIAxes5.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes6 = uiaxes(app.Tab);
            title(app.UIAxes6, '')
            xlabel(app.UIAxes6, '')
            ylabel(app.UIAxes6, '')
            app.UIAxes6.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = 'lvboqi.png';
            app.HzLabel_3 = uilabel(app.Tab);
            app.HzLabel_3.Text = 'Hz';
            app.Image2 = uiimage(app.Tab);
            app.Image2.ImageSource = 'shengcheng.png';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '低通滤波器';
            app.Button_3 = uibutton(app.Tab_2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '生成';
            app.Label_14 = uilabel(app.Tab_2);
            app.Label_14.Text = '低通';
            app.Label_15 = uilabel(app.Tab_2);
            app.Label_15.Text = '低通变换图像';
            app.Label_17 = uilabel(app.Tab_2);
            app.Label_17.Text = '设置低通滤波器截止频率';
            app.EditField_2 = uieditfield(app.Tab_2, 'numeric');
            app.EditField_2.Value = 1000;
            app.UIAxes5_3 = uiaxes(app.Tab_2);
            title(app.UIAxes5_3, '')
            xlabel(app.UIAxes5_3, '')
            ylabel(app.UIAxes5_3, '')
            app.UIAxes5_3.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes6_3 = uiaxes(app.Tab_2);
            title(app.UIAxes6_3, '')
            xlabel(app.UIAxes6_3, '')
            ylabel(app.UIAxes6_3, '')
            app.UIAxes6_3.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.Label_19 = uilabel(app.Tab_2);
            app.Label_19.Text = '源图像';
            app.Label_20 = uilabel(app.Tab_2);
            app.Label_20.Text = '未滤波的逆变换图像';
            app.UIAxes5_4 = uiaxes(app.Tab_2);
            title(app.UIAxes5_4, '')
            xlabel(app.UIAxes5_4, '')
            ylabel(app.UIAxes5_4, '')
            app.UIAxes5_4.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes6_4 = uiaxes(app.Tab_2);
            title(app.UIAxes6_4, '')
            xlabel(app.UIAxes6_4, '')
            ylabel(app.UIAxes6_4, '')
            app.UIAxes6_4.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.Image_2 = uiimage(app.Tab_2);
            app.Image_2.ImageSource = 'lvboqi.png';
            app.HzLabel_4 = uilabel(app.Tab_2);
            app.HzLabel_4.Text = 'Hz';
            app.Image2_2 = uiimage(app.Tab_2);
            app.Image2_2.ImageSource = 'shengcheng.png';
            app.Image6_2 = uiimage(app.Tab_2);
            app.Image6_2.ImageSource = 'zhuzuoquan.png';
            app.byTJUstudnetsLabel_2 = uilabel(app.Tab_2);
            app.byTJUstudnetsLabel_2.Text = 'by TJU studnets';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes4.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.Label_18 = uilabel(app.UIFigure);
            app.Label_18.Text = '图片转换系统';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'tupian.png';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'xitong.png';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = 'mukuai.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end